classdef poc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        PreRecordedTab              matlab.ui.container.Tab
        NoFileSelectedLabel         matlab.ui.control.Label
        SpokenAudioTextArea         matlab.ui.control.TextArea
        SpokenAudioTextAreaLabel    matlab.ui.control.Label
        AudioFileLabel              matlab.ui.control.Label
        SelectFileButton            matlab.ui.control.Button
        AnnotateSpeechButton        matlab.ui.control.Button
        RecordNewFileTab            matlab.ui.container.Tab
        SpokenAudioTextArea_2       matlab.ui.control.TextArea
        SpokenAudioTextArea_2Label  matlab.ui.control.Label
        SaveFileButton              matlab.ui.control.Button
        RecordButton                matlab.ui.control.Button
        MemorizeSpeakerTab          matlab.ui.container.Tab
        RecordAudioButton           matlab.ui.control.Button
        SpeakerNameEditField        matlab.ui.control.EditField
        SpeakerNameEditFieldLabel   matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        voice_recorder % Audio recorder object for live recording
        audio % Audio data being processed
        fs % Sample rate of the audio file
        bit_depth % Bit depth of the audio file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function selectFile(app, event)
            [file, path] = uigetfile('*.*', 'Select an audio file');
            app.NoFileSelectedLabel.Text = string(path) + string(file);
        end

        % Button pushed function: AnnotateSpeechButton
        function annotateSpeechButton(app, event)
            % Try to load the model
            try
                load('speech_processing_model.mat', 'speech_processing_model');
            catch
                warning('Speech Model was not found in this directory.');
                return;
            end
            
            % Try to load the audio file
            try
                [app.audio, app.fs] = audioread(app.NoFileSelectedLabel.Text);
            catch
                warning('Audio file was not found at the specified directory, or the file was not compatible with the system.');
                return;
            end
            
            % Process the audio file
            annotated_speakers = speech_processing_model.annotateAudio(app.audio, app.fs, 0.5);
            % Display the results to the plot
            cla(app.UIAxes);
            speech_processing_model.visualizeResults(app.audio, app.fs, annotated_speakers, app.UIAxes);
            % Grab the speech seperated by the speakers
            speech_list = speech_processing_model.printAnnontation(annotated_speakers);
            
            % Loop through and display the speakers to the text area
            app.SpokenAudioTextArea.Value = speech_list;
        end

        % Button pushed function: RecordButton
        function recordButton(app, event)
            % Try to load the model
            try
                load('speech_processing_model.mat', 'speech_processing_model');
            catch
                warning('Speech Model was not found in this directory.');
                return;
            end
            
            % Depending on context of the button, either record data or
            % process it
            if (strcmp(app.RecordButton.Text, "Record"))
                app.voice_recorder = audiorecorder(44100, 16, 1);
                record(app.voice_recorder);
                app.RecordButton.Text = "Stop Recording";
            else
                % Grab the audio data and reset the button
                stop(app.voice_recorder);
                app.audio = getaudiodata(app.voice_recorder);
                app.fs = app.voice_recorder.SampleRate;
                app.bit_depth = app.voice_recorder.BitsPerSample;
                app.RecordButton.Text = "Record";
                
                % Process the audio file
                annotated_speakers = speech_processing_model.annotateAudio(app.audio, app.fs, 0.5);
                % Display the results to the plot
                cla(app.UIAxes);
                speech_processing_model.visualizeResults(app.audio, app.fs, annotated_speakers, app.UIAxes);
                % Grab the speech seperated by the speakers
                speech_list = speech_processing_model.printAnnontation(annotated_speakers);
            
                % Loop through and display the speakers to the text area
                app.SpokenAudioTextArea_2.Value = speech_list;
            end
        end

        % Button pushed function: SaveFileButton
        function saveButton(app, event)
            audiowrite('live_recording.flac', app.audio, app.fs, 'BitsPerSample', app.bit_depth);
        end

        % Button pushed function: RecordAudioButton
        function memorizeSpeaker(app, event)
            
            % Try to load the model
            try
                load('speech_processing_model.mat', 'speech_processing_model');
            catch
                warning('Speech Model was not found in this directory.');
                speech_processing_model = SpeechProcessing();
                return;
            end
            
            % If button is set to record and not stop
            if (strcmp(app.RecordAudioButton.Text, "Record"))
                app.voice_recorder = audiorecorder(44100, 16, 1);
                record(app.voice_recorder);
                app.RecordAudioButton.Text = "Stop Recording";
            else
                % Grab the audio data and reset the button
                stop(app.voice_recorder);
                app.audio = getaudiodata(app.voice_recorder);
                app.fs = app.voice_recorder.SampleRate;
                app.bit_depth = app.voice_recorder.BitsPerSample;
                app.RecordAudioButton.Text = "Record";
            
                % Grab the speakers name. If field is empty, do nothing
                speaker_name = app.SpeakerNameEditField.Value;
                if (isempty(speaker_name))
                    return;
                end
                
                % Format the data for the model
                audio_clips = struct();
                audio_clips(1).audio = app.audio.';
                
                speech_processing_model.memorizeSpeaker(audio_clips, app.fs, speaker_name);
                save('speech_processing_model.mat', 'speech_processing_model');
            end
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {365, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PreRecordedTab = uitab(app.TabGroup);
            app.PreRecordedTab.Title = 'Pre-Recorded';
            app.AnnotateSpeechButton = uibutton(app.PreRecordedTab, 'push');
            app.AnnotateSpeechButton.ButtonPushedFcn = createCallbackFcn(app, @annotateSpeechButton, true);
            app.AnnotateSpeechButton.Text = 'Annotate Speech';
            app.SelectFileButton = uibutton(app.PreRecordedTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.SelectFileButton.Text = 'Select File';
            app.AudioFileLabel = uilabel(app.PreRecordedTab);
            app.AudioFileLabel.Text = 'Audio File: ';
            app.SpokenAudioTextAreaLabel = uilabel(app.PreRecordedTab);
            app.SpokenAudioTextAreaLabel.Text = 'Spoken Audio';
            app.SpokenAudioTextArea = uitextarea(app.PreRecordedTab);
            app.NoFileSelectedLabel = uilabel(app.PreRecordedTab);
            app.NoFileSelectedLabel.Text = 'No File Selected!';
            app.RecordNewFileTab = uitab(app.TabGroup);
            app.RecordNewFileTab.Title = 'Record New File';
            app.RecordButton = uibutton(app.RecordNewFileTab, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButton, true);
            app.RecordButton.Text = 'Record';
            app.SaveFileButton = uibutton(app.RecordNewFileTab, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton, true);
            app.SaveFileButton.Text = 'Save File';
            app.SpokenAudioTextArea_2Label = uilabel(app.RecordNewFileTab);
            app.SpokenAudioTextArea_2Label.Text = 'Spoken Audio';
            app.SpokenAudioTextArea_2 = uitextarea(app.RecordNewFileTab);
            app.MemorizeSpeakerTab = uitab(app.TabGroup);
            app.MemorizeSpeakerTab.Title = 'Memorize Speaker';
            app.SpeakerNameEditFieldLabel = uilabel(app.MemorizeSpeakerTab);
            app.SpeakerNameEditFieldLabel.Text = 'Speaker Name';
            app.SpeakerNameEditField = uieditfield(app.MemorizeSpeakerTab, 'text');
            app.RecordAudioButton = uibutton(app.MemorizeSpeakerTab, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @memorizeSpeaker, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Speaker Diarization')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end